classdef encode_app_5_13 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        encode_file                     matlab.ui.container.Panel
        encode_file_choose_button       matlab.ui.control.Button
        encode_file_choose_text         matlab.ui.control.Label
        encode_file_morse_paly_Button   matlab.ui.control.Button
        encode_file_morse_play2stop_Button  matlab.ui.control.Button
        encode_file_audio_save_Button   matlab.ui.control.Button
        encode_file_return2menu_Button  matlab.ui.control.Button
        encode_file_morse_plot_Button   matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        encode_file_morsetext           matlab.ui.control.EditField
        encode_hand                     matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        encode_input_hand               matlab.ui.control.EditField
        encode_input_hand_Button        matlab.ui.control.Button
        encode_hand_morse_play_Button   matlab.ui.control.Button
        encode_hand_morse_play2stop_Button  matlab.ui.control.Button
        encode_hand_morse_save_Button   matlab.ui.control.Button
        encode_hand_morse_plot_Button   matlab.ui.control.Button
        encode_hand_morse_return2menu_Button  matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        encode_hand_morsetext           matlab.ui.control.EditField
        choose_mode                     matlab.ui.container.TabGroup
        encode_gui                      matlab.ui.container.Tab
        encode_file_button              matlab.ui.control.Button
        encode_hand_button              matlab.ui.control.Button
        decode_gui                      matlab.ui.container.Tab
        wavButton                       matlab.ui.control.Button
        decodetx                        matlab.ui.control.TextArea
        Button                          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.encode_file,'visible','off')
            set(app.encode_hand,'visible','off')
            global luyin
            global R
            R = audiorecorder( 8000, 16 ,1) ;
            luyin=1
        end

        % Size changed function: encode_gui
        function encode_guiSizeChanged(app, event)
            position = app.encode_gui.Position;
        end

        % Size changed function: decode_gui
        function decode_guiSizeChanged(app, event)
            position = app.decode_gui.Position;
        end

        % Selection change function: choose_mode
        function choose_modeSelectionChanged(app, event)
            selectedTab = app.choose_mode.SelectedTab;
        end

        % Button pushed function: encode_file_button
        function encode_file_buttonButtonPushed(app, event)
            global click_1;
            click_1 = 1;
            disp(click_1)
            global click_2;
            click_2 = 0;
            set(app.choose_mode,'visible','off')
            set(app.encode_file,'visible','on')
            set(app.encode_hand,'visible','off')
            
        end

        % Button pushed function: encode_file_choose_button
        function encode_file_choose_buttonButtonPushed(app, event)
            disp("文件导入")
            [file_choose_name,file_choose_path] = uigetfile('*.txt','select file');
            global file_input
            file_input = [file_choose_path file_choose_name];
            encode;
            global textEncripted;
            data=textEncripted;
            %将加密编码与音频作为输入送入Morseencoder进行可视化
            morseencoder(data,'mensaje.wav');
            global morsetext;
            app.encode_file_morsetext.Value = morsetext;  
        end

        % Button pushed function: encode_file_morse_paly_Button
        function encode_file_morse_paly_ButtonPushed(app, event)
            morse_wav = 'mensaje.wav';
            [morse_rate,morse_fs] = audioread(morse_wav);
            global morse_player;
            morse_player = audioplayer(morse_rate,morse_fs);
            playblocking(morse_player);
        end

        % Button pushed function: encode_file_morse_play2stop_Button
        function encode_file_morse_play2stop_ButtonPushed(app, event)
            global morse_player;
            stop(morse_player);
        end

        % Button pushed function: encode_file_audio_save_Button
        function encode_file_audio_save_ButtonPushed(app, event)
            morse_wav = 'mensaje.wav';
            [y,fs] = audioread(morse_wav);
            [filename,pathname]=uiputfile('.wav','保存文件到…');
            if isequal(filename,0)||isequal(pathname,0)
                return;
            end
            fpathsave=[pathname filename];
            audiowrite(fpathsave,y,fs);
        end

        % Button pushed function: encode_file_return2menu_Button
        function encode_file_return2menu_ButtonPushed(app, event)
            set(app.choose_mode,'visible','on')
            set(app.encode_file,'visible','off')
            set(app.encode_hand,'visible','off')
        end

        % Button pushed function: encode_file_morse_plot_Button
        function encode_file_morse_plot_ButtonPushed(app, event)
            global morsecode
            figure(1);
            plot (morsecode);
        end

        % Button pushed function: encode_input_hand_Button
        function encode_input_hand_ButtonButtonPushed(app, event)
            global text_content;
            text_content = get(app.encode_input_hand,'value');
            disp("输入内容是"+text_content);
            encode
            global textEncripted;
            data=textEncripted;
            morseencoder(data,'mensaje.wav');   
            global morsetext;
            app.encode_hand_morsetext.Value = morsetext;        
        end

        % Button pushed function: encode_hand_button
        function encode_hand_buttonButtonPushed(app, event)
            global click_2;
            click_2 = 1;
            global click_1;
            click_1 = 0;
            disp("手动输入")
            set(app.choose_mode,'visible','off')
            set(app.encode_file,'visible','off')
            set(app.encode_hand,'visible','on')
        end

        % Button pushed function: encode_hand_morse_play_Button
        function encode_hand_morse_play_ButtonPushed(app, event)
            morse_wav = 'mensaje.wav';
            [morse_rate,morse_fs] = audioread(morse_wav);
            global morse_player;
            morse_player = audioplayer(morse_rate,morse_fs);
            playblocking(morse_player);
        end

        % Button pushed function: encode_hand_morse_play2stop_Button
        function encode_hand_morse_play2stop_ButtonPushed(app, event)
            global morse_player;
            stop(morse_player);
        end

        % Button pushed function: encode_hand_morse_save_Button
        function encode_hand_morse_save_ButtonPushed(app, event)
            morse_wav = 'mensaje.wav';
            [y,fs] = audioread(morse_wav);
            [filename,pathname]=uiputfile('.wav','保存文件到…');
            if isequal(filename,0)||isequal(pathname,0)
                return;
            end
            fpathsave=[pathname filename];
            audiowrite(fpathsave,y,fs);            
        end

        % Button pushed function: encode_hand_morse_plot_Button
        function encode_hand_morse_plot_ButtonPushed(app, event)
            global morsecode
            figure(1);
            plot (morsecode);
        end

        % Button pushed function: 
        % encode_hand_morse_return2menu_Button
        function encode_hand_morse_return2menu_ButtonPushed(app, event)
            set(app.choose_mode,'visible','on')
            set(app.encode_file,'visible','off')
            set(app.encode_hand,'visible','off')            
        end

        % Button pushed function: wavButton
        function wavButtonPushed(app, event)
            global decotxt
            decoder2("mensaje.wav",1);
            app.decodetx.Value=decotxt;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global luyin
            global R
            global decotxt
            if(luyin==1)
                record(R)
                luyin=2;
                app.Button.Text="停止录音";
            elseif(luyin==2)
                stop(R)
                myspeech = getaudiodata(R);
                audiowrite("abc.wav",myspeech,8000)
                decoder2("abc.wav",2);
                app.decodetx.Value=decotxt;
                luyin=1
                app.Button.Text="麦克风录音"
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.encode_file = uipanel(app.UIFigure);
            app.encode_file.Title = '选择文件编码';
            app.encode_file_choose_button = uibutton(app.encode_file, 'push');
            app.encode_file_choose_button.ButtonPushedFcn = createCallbackFcn(app, @encode_file_choose_buttonButtonPushed, true);
            app.encode_file_choose_button.Text = '打开文件';
            app.encode_file_choose_text = uilabel(app.encode_file);
            app.encode_file_choose_text.Text = '请选择文件';
            app.encode_file_morse_paly_Button = uibutton(app.encode_file, 'push');
            app.encode_file_morse_paly_Button.ButtonPushedFcn = createCallbackFcn(app, @encode_file_morse_paly_ButtonPushed, true);
            app.encode_file_morse_paly_Button.Text = '播放morse电码';
            app.encode_file_morse_play2stop_Button = uibutton(app.encode_file, 'push');
            app.encode_file_morse_play2stop_Button.ButtonPushedFcn = createCallbackFcn(app, @encode_file_morse_play2stop_ButtonPushed, true);
            app.encode_file_morse_play2stop_Button.Text = '停止播放';
            app.encode_file_audio_save_Button = uibutton(app.encode_file, 'push');
            app.encode_file_audio_save_Button.ButtonPushedFcn = createCallbackFcn(app, @encode_file_audio_save_ButtonPushed, true);
            app.encode_file_audio_save_Button.Text = '保存音频';
            app.encode_file_return2menu_Button = uibutton(app.encode_file, 'push');
            app.encode_file_return2menu_Button.ButtonPushedFcn = createCallbackFcn(app, @encode_file_return2menu_ButtonPushed, true);
            app.encode_file_return2menu_Button.Text = '返回编码主菜单';
            app.encode_file_morse_plot_Button = uibutton(app.encode_file, 'push');
            app.encode_file_morse_plot_Button.ButtonPushedFcn = createCallbackFcn(app, @encode_file_morse_plot_ButtonPushed, true);
            app.encode_file_morse_plot_Button.Text = '电码图像显示';
            app.Label_3 = uilabel(app.encode_file);
            app.Label_3.Text = '编码结果';
            app.encode_file_morsetext = uieditfield(app.encode_file, 'text');
            app.encode_hand = uipanel(app.UIFigure);
            app.encode_hand.Title = '手动输入编码';
            app.Label = uilabel(app.encode_hand);
            app.Label.Text = '请输入编码字符';
            app.encode_input_hand = uieditfield(app.encode_hand, 'text');
            app.encode_input_hand_Button = uibutton(app.encode_hand, 'push');
            app.encode_input_hand_Button.ButtonPushedFcn = createCallbackFcn(app, @encode_input_hand_ButtonButtonPushed, true);
            app.encode_input_hand_Button.Text = '确定';
            app.encode_hand_morse_play_Button = uibutton(app.encode_hand, 'push');
            app.encode_hand_morse_play_Button.ButtonPushedFcn = createCallbackFcn(app, @encode_hand_morse_play_ButtonPushed, true);
            app.encode_hand_morse_play_Button.Text = '播放morse电码';
            app.encode_hand_morse_play2stop_Button = uibutton(app.encode_hand, 'push');
            app.encode_hand_morse_play2stop_Button.ButtonPushedFcn = createCallbackFcn(app, @encode_hand_morse_play2stop_ButtonPushed, true);
            app.encode_hand_morse_play2stop_Button.Text = '停止播放';
            app.encode_hand_morse_save_Button = uibutton(app.encode_hand, 'push');
            app.encode_hand_morse_save_Button.ButtonPushedFcn = createCallbackFcn(app, @encode_hand_morse_save_ButtonPushed, true);
            app.encode_hand_morse_save_Button.Text = '保存音频';
            app.encode_hand_morse_plot_Button = uibutton(app.encode_hand, 'push');
            app.encode_hand_morse_plot_Button.ButtonPushedFcn = createCallbackFcn(app, @encode_hand_morse_plot_ButtonPushed, true);
            app.encode_hand_morse_plot_Button.Text = '电码图像显示';
            app.encode_hand_morse_return2menu_Button = uibutton(app.encode_hand, 'push');
            app.encode_hand_morse_return2menu_Button.ButtonPushedFcn = createCallbackFcn(app, @encode_hand_morse_return2menu_ButtonPushed, true);
            app.encode_hand_morse_return2menu_Button.Text = '返回主菜单';
            app.Label_2 = uilabel(app.encode_hand);
            app.Label_2.Text = '编码结果';
            app.encode_hand_morsetext = uieditfield(app.encode_hand, 'text');
            app.choose_mode = uitabgroup(app.UIFigure);
            app.choose_mode.SelectionChangedFcn = createCallbackFcn(app, @choose_modeSelectionChanged, true);
            app.encode_gui = uitab(app.choose_mode);
            app.encode_gui.SizeChangedFcn = createCallbackFcn(app, @encode_guiSizeChanged, true);
            app.encode_gui.Title = '编码模式';
            app.encode_file_button = uibutton(app.encode_gui, 'push');
            app.encode_file_button.ButtonPushedFcn = createCallbackFcn(app, @encode_file_buttonButtonPushed, true);
            app.encode_file_button.Text = {'选择文件编码（txt文件格式）'; ''};
            app.encode_hand_button = uibutton(app.encode_gui, 'push');
            app.encode_hand_button.ButtonPushedFcn = createCallbackFcn(app, @encode_hand_buttonButtonPushed, true);
            app.encode_hand_button.Text = {'手动输入编码（英文字母）'; ''};
            app.decode_gui = uitab(app.choose_mode);
            app.decode_gui.SizeChangedFcn = createCallbackFcn(app, @decode_guiSizeChanged, true);
            app.decode_gui.Title = '解码模式';
            app.wavButton = uibutton(app.decode_gui, 'push');
            app.wavButton.ButtonPushedFcn = createCallbackFcn(app, @wavButtonPushed, true);
            app.wavButton.Text = '从wav文件解码';
            app.decodetx = uitextarea(app.decode_gui);
            app.Button = uibutton(app.decode_gui, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '麦克风录音';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = encode_app_5_13

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end